classdef HybridArSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HybridArabicTextSteganographyAlgorithmUIFigure  matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        Label       matlab.ui.control.Label
        TextArea    matlab.ui.control.TextArea
        Label_2     matlab.ui.control.Label
        TextArea_2  matlab.ui.control.TextArea
        TextArea_3  matlab.ui.control.TextArea
        Label_3     matlab.ui.control.Label
        Button_5    matlab.ui.control.Button
        Button      matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        Button_3    matlab.ui.control.Button
        Button_4    matlab.ui.control.Button
        Image       matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global mappingtable
            app.Label.Text= 'يرجى إدراج نص الغلاف الخاص بك أدناه';
            app.Label_2.Text= 'من فضلك أدخل رسالتك أدناه';
            app.Label_3.Text= 'ستكون الرسالة الخفية الخاصة بك على النحو التالي';
            
            covertext = char(app.TextArea.Value);
            secrettext = char(app.TextArea_2.Value);
            
            [newcovertext] = PrepareCoverText(covertext);
            
            
            secrettext_part1 = secrettext(1:round(length(secrettext)/2));
            secrettext_part2 = secrettext(round(length(secrettext)/2)+1:length(secrettext));
            
            
            [steganotext1, mappingtable] = Kashida(newcovertext, secrettext_part1);
            [steganotext2,mappingtable] = Harakat(steganotext1, secrettext_part2,mappingtable);
            
            app.TextArea_3.Value = steganotext2;
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            clipboard('copy',char(app.TextArea_3.Value));
            app.Label.Text='النص الخفي أدناه';
            app.Label_2.Text= 'نص الغلاف المستخرج من النص الخفي';
            app.Label_3.Text='النص الأصلي المستخرج أدناه';
            app.TextArea.Value =  char(app.TextArea_3.Value);
            app.TextArea_2.Value = '';
            app.TextArea_3.Value = '';
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TextArea.Value = '';
            app.TextArea_2.Value = '';
            app.TextArea_3.Value = '';
  
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            close(app.HybridArabicTextSteganographyAlgorithmUIFigure);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global mappingtable
            
            
            steganotext = char(app.TextArea.Value);
            [covertext1, originaltext1] = DecodeSteganoPart1(steganotext,mappingtable);
            [covertext, originaltext] = DecodeSteganoPart2(covertext1, originaltext1,mappingtable);
            
            app.TextArea_2.Value = covertext;
            app.TextArea_3.Value = originaltext;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HybridArabicTextSteganographyAlgorithmUIFigure = uifigure('Visible', 'off');
            app.HybridArabicTextSteganographyAlgorithmUIFigure.Name = 'Hybrid Arabic Text Steganography Algorithm';
            app.Panel = uipanel(app.HybridArabicTextSteganographyAlgorithmUIFigure);
            app.Panel.Title = 'منطقة التشفير / فك التشفير';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'يرجى إدراج نص الغلاف الخاص بك أدناه';
            app.TextArea = uitextarea(app.Panel);
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'من فضلك أدخل رسالتك أدناه';
            app.TextArea_2 = uitextarea(app.Panel);
            app.TextArea_3 = uitextarea(app.Panel);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = 'ستكون الرسالة الخفية الخاصة بك على النحو التالي';
            app.Button_5 = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = 'نسخ النص الخفي  لفك التشفير';
            app.Button = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'تشفير';
            app.Button_2 = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'فك تشفير';
            app.Button_3 = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'امسح الكل';
            app.Button_4 = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'خروج';
            app.Image = uiimage(app.HybridArabicTextSteganographyAlgorithmUIFigure);
            app.Image.ImageSource = 'logo.PNG';
            % Show the figure after all components are created
            app.HybridArabicTextSteganographyAlgorithmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HybridArSA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HybridArabicTextSteganographyAlgorithmUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HybridArabicTextSteganographyAlgorithmUIFigure)
        end
    end
end